classdef LC2RC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ChangePaletteButton            matlab.ui.control.Button
        ShowStimTimeCheckBox           matlab.ui.control.CheckBox
        PlotRawWaveformButton          matlab.ui.control.Button
        RawWaveformDebuggingLabel      matlab.ui.control.Label
        RawWaveformDropDown            matlab.ui.control.DropDown
        RawWaveformDropDownLabel       matlab.ui.control.Label
        FolderButton                   matlab.ui.control.Button
        RemoveButton                   matlab.ui.control.Button
        RemoveCommentIDEditField       matlab.ui.control.NumericEditField
        RemoveCommentIDEditFieldLabel  matlab.ui.control.Label
        CommentsDisplayLabel           matlab.ui.control.Label
        SwapButton                     matlab.ui.control.Button
        WithIDEditField                matlab.ui.control.NumericEditField
        SwapIDEditField                matlab.ui.control.NumericEditField
        SwapLabel                      matlab.ui.control.Label
        WithLabel                      matlab.ui.control.Label
        StimLocationLabel              matlab.ui.control.Label
        StimToPlotDropDown             matlab.ui.control.DropDown
        DatasettoplotDropDown          matlab.ui.control.DropDown
        DatasettoplotDropDownLabel     matlab.ui.control.Label
        TesttxtButton                  matlab.ui.control.Button
        backfilterEditField            matlab.ui.control.NumericEditField
        backfilterEditFieldLabel       matlab.ui.control.Label
        frontfilterEditFieldLabel      matlab.ui.control.Label
        ErrorCheckButton               matlab.ui.control.Button
        frontfilterEditField           matlab.ui.control.NumericEditField
        TextArea                       matlab.ui.control.TextArea
        SaveButton                     matlab.ui.control.Button
        DataProcessingOptionsLabel     matlab.ui.control.Label
        ProcessButton                  matlab.ui.control.Button
        Tree                           matlab.ui.container.CheckBoxTree
        ChannelsincludedNode           matlab.ui.container.TreeNode
        Channel1Node                   matlab.ui.container.TreeNode
        Channel2Node                   matlab.ui.container.TreeNode
        Channel3Node                   matlab.ui.container.TreeNode
        Channel4Node                   matlab.ui.container.TreeNode
        Channel5Node                   matlab.ui.container.TreeNode
        Channel6Node                   matlab.ui.container.TreeNode
        Channel7Node                   matlab.ui.container.TreeNode
        Channel8Node                   matlab.ui.container.TreeNode
        Channel9Node                   matlab.ui.container.TreeNode
        Channel10Node                  matlab.ui.container.TreeNode
        FilePathEditField              matlab.ui.control.EditField
        FilePathEditFieldLabel         matlab.ui.control.Label
        PlotR2R1RatioCheckBox          matlab.ui.control.CheckBox
        PlotforMuscleSpineCheckBox     matlab.ui.control.CheckBox
        NormalizeCheckBox              matlab.ui.control.CheckBox
        SavePlotasCSVCheckBox          matlab.ui.control.CheckBox
        IncludeR2CheckBox              matlab.ui.control.CheckBox
        DelayEditField                 matlab.ui.control.NumericEditField
        DelayEditFieldLabel            matlab.ui.control.Label
        IntervalEditField              matlab.ui.control.NumericEditField
        IntervalEditFieldLabel         matlab.ui.control.Label
        PlotButton                     matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Phase = 2 % 1: window adjustment, 2 = data plotting 
        %Makesure WindowEditField is default 100 
        Labchart ; 
        comments = [];
        mat_file_name ;
        main_folder_path = "C:\Users\fengy\Desktop\HM\DrSayenkoLab\FW_RC_Automation"; %I need to find a way to automatically detect and change this  
        out1maxmin ;
        out2maxmin ;
        outmaxmin = {};
        records;
        Data;
        channels_to_use;
        record_names = []; % a list of stimulation locations i.e. records
        channel_names = [];% a list of muscles being stimulated, i.e. channels (named after the recording input channels) 
        RawWaveFormPlotter;
        palette = [ % curve palette
                    1.0000, 0.4980, 0.0549;  % Orange
                    0.1725, 0.6275, 0.1725;  % Green
                    0.8392, 0.1529, 0.1569;  % Red
                    0.5804, 0.4039, 0.7412;  % Purple
                    0.5490, 0.3373, 0.2941;  % Brown
                    0.8902, 0.4667, 0.7608;  % Pink
                    0.4980, 0.4980, 0.4980;  % Gray
                    0.7373, 0.7412, 0.1333;  % Yellow
                    0.0902, 0.7451, 0.8118;  % Cyan
                    0.1216, 0.4667, 0.7059;  % Blue
                ];
                    
    end
       

    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % When pushed, the button will plot depending on the phase the app is in


            %DONE Add a check to detecct whether or not there is a loaded
            %dataset. If not, send a warning & stop the function 

            %DONE, debug choosing plot logic 
                %In theory, it should just be the same thing as the for
                %loop, except we replace the for loop variable with 1
                %variable 

            %DONE: once codes are migrated, repalce all variable names with
            % 'app.' properties  
            bool_normalize = app.NormalizeCheckBox.Value; 
            bool_permuscle = app.PlotforMuscleSpineCheckBox.Value; 
            bool_ratio = app.PlotR2R1RatioCheckBox.Value; 
            
            % data = app.DatasettoplotDropDown.Value; 
            ds_idx = str2double(app.DatasettoplotDropDown.Value);
            data = app.outmaxmin{ds_idx}; 

            %DONE: let user select which record/channel to display 

            %Default channel & Record
            % curr_channel_index = 3;  % Msucle
            % curr_record_index = 1; % spine

            cla(app.UIAxes); %Clear the plot 

            switch app.Phase
                case 1 % window adjusting
                    %should plot the window, and the delay, overlaying a
                    %sample piece of data (maybe channel 3 at one of the
                    %later amplitudes 

                    %Calls the plot RC function, but modified s.t. it takes
                    %in 
               
                case 2 %do plot rc

                 str_normalize = "";
                 % str_global = "";   
                 if bool_ratio
                     str_ratio = "r2r1";
                 else
                     str_ratio = "";
                 end 
                 data_width = width(data);

             %%  Determining Normalization
                 if bool_normalize 
                     str_normalize = "normalized ";
                     if ~bool_permuscle
                         % str_global = "globally";
                         to_normalize = data{:, 3:data_width};
                        
                        % Normalize across all columns as a single unit (min-max normalization)
                        normalizedData = (to_normalize - min(to_normalize, [], 'all')) / (max(to_normalize, [], 'all') - min(to_normalize, [], 'all'));
                        
                        % Assign normalized values back to the table
                        data{:, 3:data_width} = normalizedData;
            
                     else 
                        % str_global = "per muscle";
                        data{:,3:data_width} = normalize(data{:, 3:data_width}, 'range');
                     end
                    % TODO: apply this if the corresponding checkbox is
                    % checked 
                    % writetable(data, "Output/normalized_data.csv"); % TODO, see if you can give a table a title as a property
                 end

                %% Plot naming:
                    if bool_ratio 
                        y_label = "R2R1 Ratio";
                    elseif bool_normalize
                        y_label = "Normalized Intensity";
                    else 
                        y_label = "Intensity (Volts)";
                    end 
                
                %% Different Types of Plotting
                if bool_permuscle
                 %%Plotting each stimulation for a given muscle 
            
                 %New plotting draft
                 %for i = 3:data_width %each column
                 %for r = 1:repetition %each of this will be a line in one graph

                 %TODO: fix plotting!
                 % Step 1: Change it s.t. only the current selected data
                 % (termed DATA) is plotted
                    % I think i can do this by removing the outer for loops
                 % Step 2: change it to conform with plotting to 
                 % Potential Sources of Error:
                    % Record Name
                    % Channel name
                    % De-looping logic 
                    % Using wrong property 


                    % curr_channel_index = find(ismember(app.channel_names, app.StimToPlotDropDown.Value));

                    hold(app.UIAxes, "on");
                    % muscle_name = app.StimToPlotDropDown.Value;
                    curr_channel_index = find(ismember(data.Properties.VariableNames,app.StimToPlotDropDown.Value));

                    if curr_channel_index < 1 || curr_channel_index > length(data.Properties.VariableNames)
                        warning("Critical Error, Muscle selected does not exist in data");
                        return
                    end 
            
                    for curr_record_index = 1:length(app.record_names) %Plot each range of data as a separate line
            
                        curr_range = ismember(data.record_name, app.record_names(curr_record_index));
            
                        x = data{curr_range,1};
                        channel = data{curr_range,curr_channel_index}; %DONE: change this so that it just loops through each column
                        plot(app.UIAxes,x, channel, "DisplayName", app.record_names{curr_record_index}); % DONE: change dispalyname to be the column name
                    end
                    % Customize plot
                 
                    app.UIAxes.Title.String  = str_ratio + app.StimToPlotDropDown.Value;
                    % app.UIAxes.Title.String = "DEBUGGGG";
                    app.UIAxes.XLabel.String = 'Amplitudes';
                    app.UIAxes.YLabel.String = y_label;
                    legend(app.UIAxes,'Location','best'); % or best outside;
                    app.UIAxes.XGrid = "on";
                    app.UIAxes.YGrid = "on";

                    hold(app.UIAxes, "off");
            
                    % TODO: toggle on or off based on user preference 
                    % plot_name = "Output/rcplot " + str_ratio + muscle_name + " " + str_normalize + str_global + ".png";
                    % saveas(gcf, plot_name);
                    % fprintf(['plot saved as ' plot_name])


                else %Plotting each muscle for a given stimulation location
                    %First, extract x-axis (str.values)
                    
                    curr_record_index = app.StimToPlotDropDown.ValueIndex;
                    hold(app.UIAxes, "on");
            
                    % Instead: find the indices of the rows where strcmp evaluates to
                    % true 
                    % data_range = sum(strcmp(data.record_name, record_names(r)));
                    % curr_range = (1:data_range) + data_range * (r-1) ;
                    curr_range = ismember(data.record_name, app.record_names(curr_record_index));
                    x = data{curr_range,1}; % extract the amplitudes 


                    for curr_channel_index = 3:data_width % DONE: change this st. it just goes through each column one by one
                        channel = data{curr_range,curr_channel_index}; %TODO: change this so that it just loops through each column
                        % channel = channel(curr_range);

                        
                        plot(app.UIAxes, x, channel, 'Color', app.palette(curr_channel_index,:), "DisplayName",data.Properties.VariableNames{curr_channel_index}); % Each recording location should correspond to a color
                    end
                    % As such, the data structure must treat each channel as a separate object
                    
                    % Customize plot
                    app.UIAxes.Title.String  = str_normalize + str_ratio + app.record_names(curr_record_index);
                    app.UIAxes.XLabel.String = 'Amplitudes';
                    app.UIAxes.YLabel.String = y_label;
                    legend(app.UIAxes,'Location','best'); % or best outside;
                    app.UIAxes.XGrid = "on";
                    app.UIAxes.YGrid = "on";

                    hold(app.UIAxes, "off");

                    % TODO: save according to user preference
                    % plot_name = "Output/rcplot" + curr_record_index  + str_ratio + str_normalize+ str_global + "new.png";
                    % saveas(gcf, plot_name);
                    % fprintf(['plot saved as ' plot_name])
                 
                end
      
                otherwise 
                    disp('error, phase is not 1 or 2, call the programmer to debug')
                    app.TextArea.Value = ('error, phase is not 1 or 2, call the programmer to debug');
            end 

        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            

            % TODO: add breaks such that you are able to
                % DONE 1. Modify dataset when comments are in the wrong format
                % TODO 2. Be able to plot "raw wave forms" , per alex
                % instruction
                % DONE 0: First, divide the sections up into stages of processed
                % data
                
            % TODO: Clear these things when Process button is pressed
                % 1. clear uitable
                % 2. clear uiaxes
                % 3. Remove items of the Stim Location button
                % 4. gray out swap and remove %TODO also do this after
                    % error checked button
                % 5. set swap & remove back to 0 
                % 6. Gray out plot and save %TODO enable plot and save at
                    % the end of errorchecked 

                app.UITable.Data = {};
                cla(app.UIAxes);
                app.UIAxes.Title.String = "Title";
                
                app.StimToPlotDropDown.Items = {'-----'};
                
                % Disable swap and remove
                app.SwapButton.Enable = 'off';
                app.RemoveButton.Enable = 'off';

                % 5. set swap & remove back to 0 
                app.RemoveCommentIDEditField.Value = 0;
                app.SwapIDEditField.Value = 0;
                app.WithIDEditField.Value = 0;

                % 6. Gray out plot and save 
                app.PlotButton.Enable = 'off';
                app.SaveButton.Enable = 'off';
                app.PlotRawWaveformButton.Enable = 'off';

                drawnow;


            switch app.ProcessButton.Text 
                case "Process"
                %% Converting parameters from app designer inputs
                %TODO: make this more efficient

                % stim_interval = app.IntervalEditField.Value;
                app.TextArea.Value = "Processing...";
                drawnow;


                [~,channels_to_use] = ismember(app.Tree.CheckedNodes, app.ChannelsincludedNode.Children);
                
                % for i = 1:length(app.ChannelsincludedNode.Children)
                %     node = app.ChannelsincludedNode.Children[i]
                % 
                %     if node.
                %     channels_to_use = [channels_to_use, ]
                % end
    
                % app.TextArea.Value = num2str(channels_to_use); %Sanity Check, that node operations are working 
                app.channels_to_use = channels_to_use;
    
                % AutomationDraft(app.FilePathEditField.Value);
    
                %% Preprocessing 
    
    
                %Load adi into path
                addpath adinstruments_sdk_matlab-master
                
                %%% File path parsing 
                %If the string contains the symbols \"\", get rid of them
                app.FilePathEditField.Value = erase(app.FilePathEditField.Value, ["'", '"'] );
                
                %If no extension, look for file_name.mat first, then
                %file_name.adicht in the given directory. 
                if ~isfile(app.FilePathEditField.Value)
                   if isfile([app.FilePathEditField.Value,'.mat'])
                       app.FilePathEditField.Value = [app.FilePathEditField.Value,'.mat'];
                       
                   elseif isfile([app.FilePathEditField.Value,'.adicht'])
                      app.FilePathEditField.Value = [app.FilePathEditField.Value,'.adicht'];
    
                   else 
                       error("file extension incorrect or file does not exist, please double check input");
                    end
                end


                %TODO: parse the filepath for its extensions 
                [~, file_name, ext] = fileparts(app.FilePathEditField.Value);
                
                file_path_as_list = split(app.FilePathEditField.Value, filesep);
                app.main_folder_path = strjoin(file_path_as_list(1:end-2), filesep);
               
                % Preprocesses the data if .adicht file
                if strcmp(ext, ".adicht")  
                    app.TextArea.Value = "Preprocessing adicht file, this will take a few seconds...";
                    drawnow;

                    app.mat_file_name = AlexConvert(app.FilePathEditField.Value, app.main_folder_path);
                    app.Labchart = load(app.mat_file_name); % This should load everything into workspace 
                    % Data = app.Labchart.Labchart.Data          ;
    
                    app.TextArea.Value = "Loaded adicht file into Labchart";
                    drawnow;
    
                % Load data if .mat file, but first attempt to save to input  
                elseif strcmp(ext, ".mat")
    
                    app.mat_file_name = strcat(app.main_folder_path, filesep,'Input', filesep, file_name, '.mat');
    
    
                    if ~strcmp(convertCharsToStrings(app.FilePathEditField.Value), app.mat_file_name)
                        copyfile(app.FilePathEditField.Value, app.mat_file_name);
                    end
   

                    app.Labchart = load(app.FilePathEditField.Value); % Load matlab file into App memory 
                    app.TextArea.Value = "Loaded mat file into Labchart"; 
                    drawnow;
                    
                    % Data = app.Labchart.Labchart.Data ;
    
    
                    %If the matlab file is bad, there will be error thrown
                    %later 
    
                else 
                    error("File of incorrect extension, only .adicht and .mat files are supported")
                end
    
                %% Extract Labchart Fields 
    
                % file_meta = app.Labchart.Labchart.file_meta     ;
                % record_meta = app.Labchart.Labchart.record_meta   ;
                app.comments = app.Labchart.Labchart.comments      ;
    
                %Here, print to the textarea 
    
                app.TextArea.Value = "New file loading completed...";
                drawnow;
    
                
                %% Filter away repetitive comments
                % Create unique keys for all elements in comments
                fprintf('\n ===== Filtering Comments =====\n\n' );
                
                keys = arrayfun(@(x) sprintf('%s_%d_%s', x.tick_position, x.record), app.comments, "UniformOutput",false);
                
                %find unique elements based on the keys
                [~, uniqueIdx] = unique(keys, 'stable');
                
                % FInally, filter out repetitions 
                app.comments = app.comments(uniqueIdx);
    
                
                clearvars keys uniqueIdx
                
                
                %% Format data
                
                fprintf('\n ===== Formatting Data =====\n\n' );
                
                % Convert all amplitudes into double from string
                for i = 1:numel(app.comments)
                    %First, filter out everything in the "str" field that is not a double
                    if ~isempty(regexp(app.comments(i).str, '^-?\d+$', 'once'))
                        app.comments(i).str = str2double(app.comments(i).str);
                    else
                        app.comments(i).tick_position = 0; %make the location comment the first of each record  %Genius solution to the problem at hand, guarantees the string will come first
                    end
                end
                
                %Sort data first accroding to record, then according to tick_position
                record = [app.comments.record];
                tick_pos = [app.comments.tick_position];
                
                [~,sortIdx] = sortrows([record(:), ...
                                tick_pos(:)], ...
                                [1,2]);
                app.comments = app.comments(sortIdx);
                
                clearvars sortIdx tick_pos record
    
    
                %TODO LOAD: make this the CUTOFF for data loading 
    
                %TODO LOAD: Save the processed data
    
                
                %% Format Check 
                % HAS TO BE DONE BEFORE STIM LOCS ARE FOUND
                % Probably this is an appropriate place the end the function
                % and to ask user to click next afterwards
    
                % allow users to modify the comments from interacting
                % with UITable 
    
                %DELAYED LOAD: Save the manually processed data automatically
    
    
                %PSUEDO 
                % Display table to UI 

                try
                    app.UITable.Data = struct2table(app.comments); % display table
                    app.TextArea.Value = "Comments displayed successfully"; %display to "terminal"
                    app.ProcessButton.Text = "Error Checked"; % Change button name, critical to function
                catch
                    app.TextArea.Value = "Cannot error check, data not loaded";
                    return; 
                end
                % Enable swap & remove
                app.SwapButton.Enable = 'on';
                app.RemoveButton.Enable = 'on';
     
                % When the new button is pressed, continue executing the
                % following code 
    
    
                %DONE Split the code using switch statements 
                case "Error Checked"
                stim_interval = app.IntervalEditField.Value;
                R1_delay = app.DelayEditField.Value; 
                front_filter = app.frontfilterEditField.Value;
                back_filter = app.backfilterEditField.Value;
    
                R2_delay = stim_interval + R1_delay;
                sampling_window = stim_interval - front_filter - back_filter;


                app.Data = app.Labchart.Labchart.Data;
                
                % === Stimulation Location Determination ===
    
                % 1. identify the peaks in channel 2
                    % This is done by normalizing channel 2 to be 0 to 1, every spot
                    % greater than .8 is going to be a stimulation
                    % associate the amplitude comment to the nearest 2 stimulations 
                    % Alternatively, assuming that channel 2 format is standard, we can
                    % forgo normalizing and just find spots greater than 1
                % 3. notate each stimulation in the comments section  
                % NExt step, is to make sure the new format can determine R1 & r2
                
                % determine the records we care about, to reduce running time 
                app.records = unique([app.comments.record], 'stable');
                irrelevant_records = [];
                
                % Go through each relevant record and determine all the stimulations by
                % looking at channel 2
                % Then add each of the stimulations to comments
                for idx = 1:length(app.records)
                    i = app.records(idx);
                
                    % extract channel 2 in the given record
                    chnl2 = app.Data{1,i}(2,:); % ith column of the Data cell, everything in row 2
                    % find the indices of stimulations as a list (i.e. tick position) 
                    indices = find(chnl2 > 2); 
                
                    if width(indices) == 0
                        irrelevant_records(end+1) = i;
                        continue
                    end
                
                    %Remove repetitive stimulations
                    diffs = diff(indices);
                    boundaries = [1 , find(diffs>app.IntervalEditField.Value+3) + 1];
                    filtered_indices = indices(boundaries);
                    
                    % for loop add them as str = 'stim', tick_position = index, record = i
                    len = length(app.comments);
                    for j = 1:length(filtered_indices)
                        %index = filtered_indices(j)
                        len = len + 1;
                        app.comments(len).str = 'stim';
                        app.comments(len).tick_position = filtered_indices(j);
                        app.comments(len).record = i;
                    end
                end
                
                app.records = setdiff(app.records,irrelevant_records);

                % Once again, sort data first accroding to record, then according to tick_position
                record = [app.comments.record];
                tick_pos = [app.comments.tick_position];
                
                [~,sortIdx] = sortrows([record(:), ...
                                tick_pos(:)], ...
                                [1,2]);
                app.comments = app.comments(sortIdx);

                try
                    app.UITable.Data = struct2table(app.comments); % display table
                    app.TextArea.Value = "Comments displayed successfully"; %display to "terminal"
                    app.ProcessButton.Text = "Error Checked"; % Change button name, critical to function
                catch
                    app.TextArea.Value = "Cannot error check, data not loaded";
                    return; 
                end


                clearvars filtered_indices indices diffs boundaries i idx j irrelevant_records
    
                app.TextArea.Value = "Relevant records identified as " + int2str(app.records);


                %In the followup code, first save the new table to the original
                %mat dataset, then run the rest of the code 
                app.comments =  table2struct(app.UITable.Data);
                app.Labchart.Labchart.comments = app.comments;
                % Labchart = app.Labchart;
                % save(app.mat_file_name, "Labchart");
                
                %% Locating Peak to peak %TODO: Figure out how to determine this for other datasets 
                
                fprintf('\n ===== Finding Peak to Peak =====\n\n' );


                % For each comment:
                for i = 1:numel(app.comments)
                    if ischar(app.comments(i).str)
                        x = app.comments(i);
                %For each stimulation under the amplitude
                %   Find in data{record}, the max - min in the area tick+130 to tick+230
                %   Find in data{record}, the max - min in the area tick+R1_delay+front_filter to tick+R1_delay+front_filter+sampling_window 
                
                        % DONE: parameterize 3:10, which stands for the channels
                        window_startr1 = x.tick_position+R1_delay+front_filter;
                        window_endr1 = window_startr1 + sampling_window;
                
                        windowsr1 = app.Data{x.record}(app.channels_to_use, window_startr1:window_endr1);
                        maxminr1 = max(windowsr1, [],2) - min(windowsr1,[],2);
                
                        window_startr2 = x.tick_position+R2_delay+front_filter;
                        window_endr2 = window_startr2 + sampling_window;
                
                        windowsr2 = app.Data{x.record}(app.channels_to_use, window_startr2:window_endr2);
                        maxminr2 = max(windowsr2, [],2) - min(windowsr2,[],2);
                %   Store this information to maxminr1 field of app.comments
                        app.comments(i).maxminr1 = maxminr1;
                        app.comments(i).maxminr2 = maxminr2;
                    %else, the fields are empty --> []
                
                    end 
                end
                
                fprintf('\n ===== Raw Peak to Peak Data Calculated =====\n\n' );
                
                
                clearvars R1_delay R2_delay windowsr2 windowsr1 maxminr2 maxminr1 x i window_size
                
                %% Maxmin calculation
                
                %TODO: make sure that the positional comments are integrated into the name
                %of the stimulations
                
                %   Then, while averaging each max-min, merge elements that has
                %   same record and amplitude
                
                % Output 
                
                fprintf('\n ===== Calculating Peak to Peak related data =====\n\n' );
                
                lastamp = 1;
                last_loc = '';
                
                
                rows_to_remove = [];
                
                for i = 1:numel(app.comments)
                    x = app.comments(i);
                    if ischar(x.str)
                        if strcmp(x.str, 'stim')
                            app.comments(lastamp).sumr1 = app.comments(lastamp).sumr1 + x.maxminr1;
                            app.comments(lastamp).sumr2 = app.comments(lastamp).sumr2 + x.maxminr2;
                            app.comments(lastamp).count = app.comments(lastamp).count + 1;
                            app.comments(lastamp).record_name = last_loc;
                        else 
                            last_loc = x.str;
                            rows_to_remove(end+1) = i;
                        end
                
                    elseif isnumeric(x.str)
                        lastamp = i;
                        app.comments(lastamp).sumr1 = 0;
                        app.comments(lastamp).sumr2 = 0;
                        app.comments(lastamp).count = 0;
                        app.comments(lastamp).record_name = '';
                       
                    end 
                end
                
                %Remove irrelevant rows from filtered comments
                
                app.comments(rows_to_remove) = [];
                
                %Outputting
                
                %filter array to only have amplitude left
                %TODO, change the name output here 
                output = app.comments(arrayfun(@(x) isnumeric(x.str), app.comments));
                
                %Sanity check
                % if len(output.averager1) == len(channels_to_use)
                
                % else fprintf('\n ERROR! Mismatch between channels \n\n')
                
                % end
                fprintf('\n ===== Relevant Data Calculated =====\n\n' );
                
                clearvars lastamp

                %% Data cleaning
                
                fprintf('\n ===== Preparing Output =====\n\n' );
                
                
                %Calculate average & store into new struct 
    
                %Extract the names of each channel 
                channel_meta = app.Labchart.Labchart.channel_meta  ;
                app.channel_names = string({channel_meta(app.channels_to_use).name});


                % Assume each amp value has the "record_name" field 
                app.record_names = unique({output.record_name}, 'stable'); %For some reason this gives you results in a very random order --> Fixed by adding 'stable'
                
                % Final Calculation
                newstructr1 = struct( 'amps', {output.str});
                newstructr2 = struct( 'amps', {output.str});
                
                
                for i = 1:numel(output)
                    output(i).averager1 = output(i).sumr1 / output(i).count;
                    output(i).averager2 = output(i).sumr2 / output(i).count;
                    newstructr1(i).("record_name") = output(i).record_name;
                    newstructr2(i).("record_name") = output(i).record_name;
                
                    for j = 1:numel(output(i).averager1)
                        %store into new struct 
                        % TODO: add parameters that indicate which fields are needed
                        % TODO: add checks to include other information
                
                        newstructr1(i).(app.channel_names(j)) = output(i).averager1(j);
                        newstructr2(i).(app.channel_names(j)) = output(i).averager2(j);
                    end
                end 
                % Should Process the data 
                % Should create nodes in the channels included tree
                % Should show raw comments to error check
                % Shou
    
                app.out1maxmin = struct2table(newstructr1);
                app.out2maxmin = struct2table(newstructr2);
                app.outmaxmin{1} = app.out1maxmin;
                app.outmaxmin{2} = app.out2maxmin;
    

                % TODO: if needed, add code that also updates datasettoplot
                % function
                % DONE: ADD code that updates the stimtoplot dropdown
    
                value = app.PlotforMuscleSpineCheckBox.Value;

                %***
                if value == true
                    app.StimLocationLabel.Text = "Recording Location";
                    app.StimToPlotDropDown.Items = app.channel_names;
                    app.RawWaveformDropDownLabel.Text = "Stimulation RW";
                    app.RawWaveformDropDown.Items = app.record_names;
                else 
                    app.StimLocationLabel.Text = "Stimulation Location";
                    app.StimToPlotDropDown.Items = app.record_names; 
                    app.RawWaveformDropDownLabel.Text = "Recording RW";
                    app.RawWaveformDropDown.Items = app.channel_names;
                end


                app.ProcessButton.Text = "Process"; 


                % state change 
                %Disable swap and remove
                app.SwapButton.Enable = 'off';
                app.RemoveButton.Enable = 'off';

                %Enable plotting and saving
                app.PlotButton.Enable = 'on';
                app.SaveButton.Enable = 'on';
                app.PlotRawWaveformButton.Enable = 'on';

                otherwise
                    disp('error, phase is not 1 or 2, call the programmer to debug')
                    app.TextArea.Value = ('error, phase is not 1 or 2, call the programmer to debug');

            end
        end

        % Button pushed function: TesttxtButton
        function TesttxtButtonPushed(app, event)

            app.TextArea.Value = app.RemoveButton.Text;
            % Print app.record_names and app.channel_names 
        end

        % Value changed function: PlotforMuscleSpineCheckBox
        function PlotforMuscleSpineCheckBoxValueChanged(app, event)
            value = app.PlotforMuscleSpineCheckBox.Value;

            
            %should i add a validity check? 
            try
                if value == true
                    app.StimLocationLabel.Text = "Stimulation Location";
                    app.StimToPlotDropDown.Items = app.channel_names;
                    app.RawWaveformDropDownLabel.Text = "Recording RW";
                    app.RawWaveformDropDown.Items = app.record_names;
                else 
                    app.StimLocationLabel.Text = "Recording Location";
                    app.StimToPlotDropDown.Items = app.record_names; 
                    app.RawWaveformDropDownLabel.Text = "Stimulation RW";
                    app.RawWaveformDropDown.Items = app.channel_names;
                end
            catch
                app.TextArea.Value = "Error switching Stim and Recording locations";

                
            end


        end

        % Button pushed function: ErrorCheckButton
        function ErrorCheckButtonPushed(app, event)
            % This function prints .comments to the UITable, allowing for
            % manual correction 

            % TODO: incorporate this into processing BECAUSE U NEED TO
            % ADJUST BEFORE THEY PROCESS!


            try
                app.UITable.Data = struct2table(app.comments);
                app.TextArea.Value = "Error Checking: Comments displayed successfully";
                drawnow;
            catch
                app.TextArea.Value = "Cannot error check, data not loaded";
                return; 
            end

            % TODO: make swap row UI functions show up! 


            %TODO: Code a functionality that allows swtiching rows easily

            %ALTERNATIVELY, look for a location either within the record or before
            %it, in the processing file  


        end

        % Button pushed function: SwapButton
        function SwapButtonPushed(app, event)

            %if table exists, ALTERNATIVELY, if app.comments exist
            if ~isempty(app.comments)
            %   check if the rows with the given IDs exist 
                % if rows exist, swap row a with row b 
                a_index = find(ismember([app.comments.id], app.SwapIDEditField.Value));
                b_index = find(ismember([app.comments.id], app.WithIDEditField.Value));
                if ~isempty(a_index) && ~isempty(b_index)

                    row_a = app.comments(a_index); 
                    row_b = app.comments(b_index);

                    app.comments(a_index) = row_b;
                    app.comments(b_index) = row_a;

                    app.UITable.Data = struct2table(app.comments);
                
                else
                    % else warning saying that rows don't exist 
                    app.TextArea.Value = "Cannot swap. One of the rows' ID doesn't exist";
                end
            else 
                % warning saying that the table doesn't exist 
                app.TextArea.Value = "Swap failed. App.Comments does not exist";
            end
            

        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
        %Double Click to Confirm

        switch app.RemoveButton.Text
            case 'Remove!'         %Phase1, when value = remove
                %check if row iwht ID exists
                a_index = find(ismember([app.comments.id], app.RemoveCommentIDEditField.Value));
                if ~isempty(a_index)
                    %if so, enter phase 2, change value = confirm
                    app.RemoveButton.Text = "Confirm!";
                    app.RemoveButton.FontColor = [1.00,0,0];
                    
                    app.TextArea.Value = "Please confirm removal of row with id" + num2str(a_index);
                else 
                    app.TextArea.Value = "Row with Given ID DoesNotExist";
                    
                end
            case 'Confirm!'        %Phase2, value = confirm

                %check if row with ID exists 
                a_index = find(ismember([app.comments.id], app.RemoveCommentIDEditField.Value));
                if ~isempty(a_index)
                    %if so, remove row with ID
                    app.comments(a_index) = [];
                    %Display table once more
                    app.UITable.Data = struct2table(app.comments);
                    %change value back to remove
                    app.RemoveButton.Text = "Remove!";
                    app.TextArea.Value = "Successfully removed row with id" + num2str(a_index);
                else 
                    app.TextArea.Value = "Row with Given ID DoesNotExist";
                end
            otherwise
                app.TextArea.Value = "Error, somehow removebutton is in an incorrect state";
        end 
        

        %If it doesn't exist at any point, change value back to confirm,
        %display error to text area 
        end

        % Button pushed function: PlotRawWaveformButton
        function PlotRawWaveformButtonPushed(app, event)
            %POP UP INTO DIFFERNT WINDOW, Sub-App w/ Looong UI FIGURE,
            %The sub UI should literally plot the waveform, intesity over
            %time, probably from -1 to 1 or hwoever much necessary 

            app.PlotRawWaveformButton.Enable = 'off';

            %DONE: Get the Record and Channel Number for DATA 
            
            data = app.Data(app.records);
            data = cellfun(@(x) x(app.channels_to_use,:), data, 'UniformOutput',false);
            
            stim_loc = app.record_names;
            rec_loc = app.channel_names; %My fault at naming, the rec here is not which record (i.e. spine level i.e. stimulation location), but which recording location (i.e. muscle)
            
            if app.PlotforMuscleSpineCheckBox.Value % If this is true, 
                curr_rec_idx = app.StimToPlotDropDown.ValueIndex; %i.e. muscle idx i.e. channel iidx
                curr_stim_idx = app.RawWaveformDropDown.ValueIndex;
            else
                curr_rec_idx = app.RawWaveformDropDown.ValueIndex;
                curr_stim_idx = app.StimToPlotDropDown.ValueIndex; %i.e. muscle idx i.e. channel iidx
            end 


            %TODO: see if you can maybe open multiple instances by making
            %this an array of RawWaveForms 
            app.RawWaveFormPlotter = RawWaveForm(app, data, stim_loc, curr_stim_idx, rec_loc, curr_rec_idx, app.palette);


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            %TODO: Iteratively kill, or delete, each process, or subapp, when
            %implementing the multi instances version
            
            delete(app.RawWaveFormPlotter)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'str'; 'id'; 'tick_position'; 'channel'; 'record'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Enable = 'off';
            app.PlotButton.Text = 'Plot';
            app.IntervalEditFieldLabel = uilabel(app.UIFigure);
            app.IntervalEditFieldLabel.Text = 'Interval';
            app.IntervalEditField = uieditfield(app.UIFigure, 'numeric');
            app.IntervalEditField.Value = 100;
            app.DelayEditFieldLabel = uilabel(app.UIFigure);
            app.DelayEditFieldLabel.Text = 'Delay';
            app.DelayEditField = uieditfield(app.UIFigure, 'numeric');
            app.DelayEditField.Value = 130;
            app.IncludeR2CheckBox = uicheckbox(app.UIFigure);
            app.IncludeR2CheckBox.Text = 'Include R2';
            app.IncludeR2CheckBox.Value = true;
            app.SavePlotasCSVCheckBox = uicheckbox(app.UIFigure);
            app.SavePlotasCSVCheckBox.Text = 'Save Plot as CSV';
            app.SavePlotasCSVCheckBox.Value = true;
            app.NormalizeCheckBox = uicheckbox(app.UIFigure);
            app.NormalizeCheckBox.Text = 'Normalize';
            app.PlotforMuscleSpineCheckBox = uicheckbox(app.UIFigure);
            app.PlotforMuscleSpineCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotforMuscleSpineCheckBoxValueChanged, true);
            app.PlotforMuscleSpineCheckBox.Text = 'Plot for Muscle / Spine';
            app.PlotR2R1RatioCheckBox = uicheckbox(app.UIFigure);
            app.PlotR2R1RatioCheckBox.Text = 'Plot R2/R1 Ratio ';
            app.FilePathEditFieldLabel = uilabel(app.UIFigure);
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.UIFigure, 'text');
            app.FilePathEditField.Value = '/Users/franciswang/Documents/GitHub/FW_RC_Automation/Input/20240408_RTA002_EPA3_RC ONLY TSS.mat';
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.ChannelsincludedNode = uitreenode(app.Tree);
            app.ChannelsincludedNode.Text = 'Channels included';
            app.Channel1Node = uitreenode(app.ChannelsincludedNode);
            app.Channel1Node.Text = 'Channel1';
            app.Channel2Node = uitreenode(app.ChannelsincludedNode);
            app.Channel2Node.Text = 'Channel2';
            app.Channel3Node = uitreenode(app.ChannelsincludedNode);
            app.Channel3Node.Text = 'Channel3';
            app.Channel4Node = uitreenode(app.ChannelsincludedNode);
            app.Channel4Node.Text = 'Channel4';
            app.Channel5Node = uitreenode(app.ChannelsincludedNode);
            app.Channel5Node.Text = 'Channel5';
            app.Channel6Node = uitreenode(app.ChannelsincludedNode);
            app.Channel6Node.Text = 'Channel6';
            app.Channel7Node = uitreenode(app.ChannelsincludedNode);
            app.Channel7Node.Text = 'Channel7';
            app.Channel8Node = uitreenode(app.ChannelsincludedNode);
            app.Channel8Node.Text = 'Channel8';
            app.Channel9Node = uitreenode(app.ChannelsincludedNode);
            app.Channel9Node.Text = 'Channel9';
            app.Channel10Node = uitreenode(app.ChannelsincludedNode);
            app.Channel10Node.Text = 'Channel10';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.Channel3Node, app.Channel4Node, app.Channel5Node, app.Channel6Node, app.Channel7Node, app.Channel8Node, app.Channel9Node, app.Channel10Node];
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.DataProcessingOptionsLabel = uilabel(app.UIFigure);
            app.DataProcessingOptionsLabel.Text = 'Data Processing Options';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.frontfilterEditField = uieditfield(app.UIFigure, 'numeric');
            app.frontfilterEditField.Value = 20;
            app.ErrorCheckButton = uibutton(app.UIFigure, 'push');
            app.ErrorCheckButton.ButtonPushedFcn = createCallbackFcn(app, @ErrorCheckButtonPushed, true);
            app.ErrorCheckButton.Text = 'Error Check';
            app.frontfilterEditFieldLabel = uilabel(app.UIFigure);
            app.frontfilterEditFieldLabel.Text = 'frontfilter';
            app.backfilterEditFieldLabel = uilabel(app.UIFigure);
            app.backfilterEditFieldLabel.Text = 'backfilter';
            app.backfilterEditField = uieditfield(app.UIFigure, 'numeric');
            app.backfilterEditField.Value = 15;
            app.TesttxtButton = uibutton(app.UIFigure, 'push');
            app.TesttxtButton.ButtonPushedFcn = createCallbackFcn(app, @TesttxtButtonPushed, true);
            app.TesttxtButton.Text = 'Testtxt';
            app.DatasettoplotDropDownLabel = uilabel(app.UIFigure);
            app.DatasettoplotDropDownLabel.Text = 'Dataset to plot';
            app.DatasettoplotDropDown = uidropdown(app.UIFigure);
            app.DatasettoplotDropDown.Items = {'R1', 'R2'};
            app.DatasettoplotDropDown.ItemsData = {'1', '2'};
            app.DatasettoplotDropDown.Value = '1';
            app.StimToPlotDropDown = uidropdown(app.UIFigure);
            app.StimToPlotDropDown.Items = {'-----'};
            app.StimToPlotDropDown.Value = '-----';
            app.StimLocationLabel = uilabel(app.UIFigure);
            app.StimLocationLabel.Text = 'Stim Location';
            app.WithLabel = uilabel(app.UIFigure);
            app.WithLabel.Text = 'With ID';
            app.SwapLabel = uilabel(app.UIFigure);
            app.SwapLabel.Text = 'Swap ID';
            app.SwapIDEditField = uieditfield(app.UIFigure, 'numeric');
            app.WithIDEditField = uieditfield(app.UIFigure, 'numeric');
            app.SwapButton = uibutton(app.UIFigure, 'push');
            app.SwapButton.ButtonPushedFcn = createCallbackFcn(app, @SwapButtonPushed, true);
            app.SwapButton.Enable = 'off';
            app.SwapButton.Text = 'Swap!';
            app.CommentsDisplayLabel = uilabel(app.UIFigure);
            app.CommentsDisplayLabel.Text = 'Comments Display';
            app.RemoveCommentIDEditFieldLabel = uilabel(app.UIFigure);
            app.RemoveCommentIDEditFieldLabel.Text = 'Remove Comment ID ';
            app.RemoveCommentIDEditField = uieditfield(app.UIFigure, 'numeric');
            app.RemoveButton = uibutton(app.UIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Enable = 'off';
            app.RemoveButton.Text = 'Remove!';
            app.FolderButton = uibutton(app.UIFigure, 'push');
            app.FolderButton.Tooltip = {'TODO: allow user to click on this and find the item from windows explorer'};
            app.FolderButton.Text = 'Folder';
            app.RawWaveformDropDownLabel = uilabel(app.UIFigure);
            app.RawWaveformDropDownLabel.Text = 'Raw Waveform';
            app.RawWaveformDropDown = uidropdown(app.UIFigure);
            app.RawWaveformDropDown.Items = {'-----'};
            app.RawWaveformDropDown.Value = '-----';
            app.RawWaveformDebuggingLabel = uilabel(app.UIFigure);
            app.RawWaveformDebuggingLabel.Text = 'Raw Waveform Debugging';
            app.PlotRawWaveformButton = uibutton(app.UIFigure, 'push');
            app.PlotRawWaveformButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRawWaveformButtonPushed, true);
            app.PlotRawWaveformButton.Enable = 'off';
            app.PlotRawWaveformButton.Text = 'Plot Raw Waveform';
            app.ShowStimTimeCheckBox = uicheckbox(app.UIFigure);
            app.ShowStimTimeCheckBox.Tooltip = {'TODO: Implement fucn to Allow display or not display stimulation times after each amplitude'};
            app.ShowStimTimeCheckBox.Text = 'Show Stim Time';
            app.ChangePaletteButton = uibutton(app.UIFigure, 'push');
            app.ChangePaletteButton.Tooltip = {'TODO: allow use to enter a new palette as an array or whatever is most convenient'};
            app.ChangePaletteButton.Text = 'Change Palette';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LC2RC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end