classdef RawWaveForm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RecordingLocationDropDown    matlab.ui.control.DropDown
        RecordingLocationDropDown_2Label  matlab.ui.control.Label
        StimulationLocationDropDown  matlab.ui.control.DropDown
        StimulationLocationDropDownLabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mainapp % App which called RawWaveForm App
        Data % Data to be plotted
        stim_loc
        curr_stim_idx
        rec_loc
        curr_rec_idx
        bool_stim %1 if curr_loc is stim loc, 0 if curr_loc is recording loc
        palette %Colormap for muscles


    end
    
    methods (Access = private)
        
        function updateplot(app)
            data = app.Data{app.StimulationLocationDropDown.ValueIndex}(app.RecordingLocationDropDown.ValueIndex,:);

            plot(app.UIAxes, data, 'Color', app.palette(app.RecordingLocationDropDown.ValueIndex,:));
            xlabel(app.UIAxes,'Tick Position');
            ylabel(app.UIAxes,'Amplitude');    
            title(app.UIAxes,['Raw Wave Form for ' app.StimulationLocationDropDown.Value ' at ' app.RecordingLocationDropDown.Value]);


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, data, stim_loc, curr_stim_idx, rec_loc, curr_rec_idx, palette)
            app.Data = data;

            %TODO: OVERHAUL
            % Need:
                % DONE data 
                % Stim Loc
                % Curr Stim Loc Idx
                % Rec Loc
                % Curr Rec Loc Idx

            app.stim_loc = stim_loc;
            app.curr_stim_idx = curr_stim_idx;
            app.rec_loc = rec_loc;
            app.curr_rec_idx = curr_rec_idx;
            
            % store main app in property
            % % For now, the main app is LC2RC
            app.mainapp = mainapp;

            app.StimulationLocationDropDown.Items = app.stim_loc;
            app.RecordingLocationDropDown.Items = app.rec_loc;
            app.StimulationLocationDropDown.Value = app.stim_loc{curr_stim_idx};
            app.RecordingLocationDropDown.Value = app.rec_loc{curr_rec_idx};
            app.palette = palette;


            % % Now, plot the UIAxes with the input parameters 
            % TODO: this doesn't seem to work when you put it in the
            % STARTUPFCN; debug that --> turns out it didnt work in
            % general, fixed now
            data = app.Data{app.StimulationLocationDropDown.ValueIndex}(app.RecordingLocationDropDown.ValueIndex,:);

            plot(app.UIAxes, data, 'Color', app.palette(app.RecordingLocationDropDown.ValueIndex,:));
            xlabel(app.UIAxes,'Tick Position');
            ylabel(app.UIAxes,'Amplitude');    
            title(app.UIAxes,['Raw Wave Form for ' app.StimulationLocationDropDown.Value ' at ' app.RecordingLocationDropDown.Value]);
        end

        % Callback function: not associated with a component
        function PlotRawWaveformButtonPushed(app, event)
            
            data = app.Data{app.StimulationLocationDropDown.ValueIndex}(app.RecordingLocationDropDown.ValueIndex,:);

            plot(app.UIAxes, data, 'Color', app.palette(app.RecordingLocationDropDown.ValueIndex,:));
            xlabel(app.UIAxes,'Tick Position');
            ylabel(app.UIAxes,'Amplitude');    
            title(app.UIAxes,['Raw Wave Form for ' app.StimulationLocationDropDown.Value ' at ' app.RecordingLocationDropDown.Value]);


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % checking to see if the main app is still open 
            % if isvalid(app.mainapp) % % --> Can't trust the tutorial sometimes 
            
            app.mainapp.PlotRawWaveformButton.Enable = 'on';
            
            %Delete this instance of RawWaveForm Plot
            delete(app)
            
        end

        % Value changed function: StimulationLocationDropDown
        function StimulationLocationDropDownValueChanged(app, event)
            % value = app.StimulationLocationDropDown.Value;
            updateplot(app);
        end

        % Value changed function: RecordingLocationDropDown
        function RecordingLocationDropDownValueChanged(app, event)
            % value = app.RecordingLocationDropDown.Value;
            updateplot(app);

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Raw Wave Form for ------')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StimulationLocationDropDownLabel = uilabel(app.UIFigure);
            app.StimulationLocationDropDownLabel.Text = 'Stimulation Location';
            app.StimulationLocationDropDown = uidropdown(app.UIFigure);
            app.StimulationLocationDropDown.Items = {'-----'};
            app.StimulationLocationDropDown.ValueChangedFcn = createCallbackFcn(app, @StimulationLocationDropDownValueChanged, true);
            app.StimulationLocationDropDown.Value = '-----';
            app.RecordingLocationDropDown_2Label = uilabel(app.UIFigure);
            app.RecordingLocationDropDown_2Label.Text = 'Recording Location';
            app.RecordingLocationDropDown = uidropdown(app.UIFigure);
            app.RecordingLocationDropDown.Items = {'-----'};
            app.RecordingLocationDropDown.ValueChangedFcn = createCallbackFcn(app, @RecordingLocationDropDownValueChanged, true);
            app.RecordingLocationDropDown.Value = '-----';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RawWaveForm(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end